classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        PreProcessProcessTab       matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        ExtendedNodeListLabel      matlab.ui.control.Label
        ENL_Table                  matlab.ui.control.Table
        SolveButton                matlab.ui.control.Button
        APanel                     matlab.ui.container.Panel
        Field_A                    matlab.ui.control.NumericEditField
        EPanel                     matlab.ui.container.Panel
        Field_E                    matlab.ui.control.NumericEditField
        ClearBCsButton             matlab.ui.control.Button
        NodePanel                  matlab.ui.container.Panel
        NodeNumberEditField        matlab.ui.control.NumericEditField
        MagnitudePanel             matlab.ui.container.Panel
        MagnitudeEditField         matlab.ui.control.NumericEditField
        ClearallButton             matlab.ui.control.Button
        ApplyBCButton              matlab.ui.control.Button
        TypeButtonGroup            matlab.ui.container.ButtonGroup
        DisplacementButton         matlab.ui.control.RadioButton
        ForceButton                matlab.ui.control.RadioButton
        DirectionButtonGroup       matlab.ui.container.ButtonGroup
        YButton                    matlab.ui.control.RadioButton
        XButton                    matlab.ui.control.RadioButton
        ElementLabelSwitch         matlab.ui.control.Switch
        ElementLabelSwitchLabel    matlab.ui.control.Label
        NodeLabelSwitch            matlab.ui.control.Switch
        NodeLabelSwitchLabel       matlab.ui.control.Label
        ElementsLabel              matlab.ui.control.Label
        NodeListLabel              matlab.ui.control.Label
        NodeCoordinatesLabel       matlab.ui.control.Label
        NodeSizeSlider             matlab.ui.control.Slider
        NodeSizeSliderLabel        matlab.ui.control.Label
        EndNode                    matlab.ui.control.NumericEditField
        EndNodeEditFieldLabel      matlab.ui.control.Label
        StartNode                  matlab.ui.control.NumericEditField
        StartNodeEditFieldLabel    matlab.ui.control.Label
        Y_coor                     matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel  matlab.ui.control.Label
        X_coor                     matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel  matlab.ui.control.Label
        ReadNodeElementListButton  matlab.ui.control.Button
        ElementListLabel           matlab.ui.control.Label
        EL_Table                   matlab.ui.control.Table
        GenerateElementButton      matlab.ui.control.Button
        NL_Table                   matlab.ui.control.Table
        GenerateNodeButton         matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        ResultsTab                 matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        ButtonGroup                matlab.ui.container.ButtonGroup
        Copper                     matlab.ui.control.ToggleButton
        Cool                       matlab.ui.control.ToggleButton
        Jet                        matlab.ui.control.ToggleButton
        DisplacementsTable         matlab.ui.control.Table
        MagnificationSlider        matlab.ui.control.Slider
        MagnificationSliderLabel   matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    
        NL % Description
        EL
        NoN
        NoE
        NodeSize = 10
        NodeSwitch = "On"
        ElementSwitch = "On"
        colorMap = jet
        ENL 
        PD
        E
        A
        Mag = 1
        

         % Description
    end


    methods (Access = public)
        
        
        function results = updateAxes(app)
            
            cla(app.UIAxes);
            
            hold(app.UIAxes,"on");
            
            colormap(app.UIAxes, app.colorMap)
            colorbar(app.UIAxes);
    
            for i = 1 : app.NoE
            H=plot(app.UIAxes,[app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)] , [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)],'Linewidth', 3, 'Color', 'k');
            H.Color(4)=0.2; 
            end
            
            for i = 1 :app.NoN
                plot(app.UIAxes , app.NL(i,1),app.NL(i,2),'o','MarkerSize', app.NodeSize,'MarkerEdgeColor', 'k', 'MarkerFaceColor','r')
            end

            switch app.NodeSwitch
                case "On"
                    for i = 1:app.NoN
                    
                        text (app.UIAxes, app.NL(i,1), app.NL(i,2),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                    end
            end
            switch app.ElementSwitch
                case "On"
                    for i = 1:app.NoE
                        plot(app.UIAxes,(app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1))/2,(app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2))/2,"-s",'MarkerSize', app.NodeSize,'MarkerEdgeColor', 'k', 'MarkerFaceColor','b')
                        text(app.UIAxes,(app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1))/2,(app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2))/2,num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')    
                    end
            
            end
        end
          
        
        function result = FEM11(app)
            [app.ENL,DOFs,DOCs] = assign_BCs(app.ENL, app.NoN, app.PD);
            K = assemble_stiffness(app.ENL,app.EL,app.NL,app.E,app.A);
            Fp = assemble_forces(app.ENL, app.NL);
            Up = assemble_displacements(app.ENL, app.NL);
            K_reduced = K(1:DOFs,1:DOFs);
            Kpu = K(1:DOFs,1:DOFs);
            Kpp = K(1:DOFs,DOFs+1:DOFs+DOCs);
            Kuu = K(DOFs+1:DOFs+DOCs,1:DOFs);
            Kup = K(DOFs+1:DOFs+DOCs,DOFs+1:DOFs+DOCs);
            F = Fp - Kpp * Up;
            Uu = inv(Kpu) * F;           
            Fu = Kuu*Uu + Kup*Up;
            app.ENL = update_nodes(app.ENL,Uu,app.NL,Fu);
            post_process(app)

        end
        
        function result = post_process(app)
            mag = app.Mag;
            Node_flag = app.NodeSwitch;
            Element_flag =  app.ElementSwitch ;

            cla(app.UIAxes2);
            hold(app.UIAxes2,"on");
            for i = 1 : app.NoE
                H=plot(app.UIAxes2,[app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)] , [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)],'Linewidth', 3, 'Color', 'k');
                H.Color(4)=0.2;
            end
            
            for i = 1 :app.NoN
                plot(app.UIAxes2,app.NL(i,1),app.NL(i,2),'o','MarkerSize',20,'MarkerEdgeColor', 'k', 'MarkerFaceColor','r')
            end
            colormap(app.UIAxes2, app.colorMap)
            for i = 1:app.NoE
                x = [app.ENL(app.EL(i,1),1)+mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+mag*app.ENL(app.EL(i,2),9)];
                y = [app.ENL(app.EL(i,1),2)+mag*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+mag*app.ENL(app.EL(i,2),10)];
                z= zeros(size(x));
                sigma=app.E*(sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2 + (app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)...
                    / (sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2 + (app.ENL(app.EL(i,2),2))^2)));
                col= [sigma sigma];
                
                surface(app.UIAxes2,[x;x],[y;y],[z;z],[col;col],'facecol','no','edgecol','interp','linew',4);
            end
            axis(app.UIAxes2,"equal")
            colorbar(app.UIAxes2)
            %elements
            if isequal(Element_flag,'On')
                for i = 1 : app.NoE
                    text(app.UIAxes2,(app.ENL(app.EL(i,1),1)+mag*app.ENL(app.EL(i,1),9)+app.ENL(app.EL(i,2),1)+mag*app.ENL(app.EL(i,2),9))/2,(app.ENL(app.EL(i,1),2)+mag*app.ENL(app.EL(i,1),10)+app.ENL(app.EL(i,2),2)+mag*app.ENL(app.EL(i,2),10))/2,num2str(i),'Color','k','FontSize',16,'HorizontalAlignment','center')
                end
            end
            %nodes
            for i =1 : app.NoN
                plot(app.UIAxes2, app.ENL(i,1)+mag*app.ENL(i,9),app.ENL(i,2)+mag*app.ENL(i,10),'o','MArkerSize',16, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0.7,0.9])
            end
            %node numbers
            if isequal(Node_flag, 'On')
                for i = 1:app.NoN
                    text (app.UIAxes2, app.ENL(i,1)+mag*app.ENL(i,9), app.ENL(i,2)+mag*app.ENL(i,10),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                end
            end
            hold(app.UIAxes2,"off");

        end
        
%         function results = plot_arrow(app,x,y,dir)
% 
%             
%             if dir == 0
%                 x1 = x + 0.2;
%                 y1 = y;
%             elseif dir == 1
%                 x1 = x;
%                 y1 = y + 0.2;
%             end
%             %quiver(app.UIAxes,x,y,x1,y1,'r','filled','LineWidth',1)
%             quiver(app.UIAxes,x,y,x1,y1,'linewidth',2,'color','r')
% %             set(h,'parent', gca, ...
% %     'position', [x1 y1 x2 y2], ...
% %     'HeadLength', 1000, 'HeadWidth', 100, 'HeadStyle', ...
% %     'Color', "r", 'LineWidth', 3);
% %             
%         end
%         
%         function results = bc_displacement(app,x,y,dir)
% 
%             if dir == 1 %y direction
%                 marker = "^";
%                 y = y -0.00035;
%             elseif dir == 0 %x direction
%                 marker = ">";
%                 %x = x - app.NodeSize * 0.00035;
%             end
%             plot(app.UIAxes , x,y,marker,'MarkerSize', app.NodeSize,'MarkerEdgeColor', 'k', 'MarkerFaceColor',[0.9290 0.6940 0.1250])
% 
% 
%             
%         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: X_coor
        function X_coorValueChanged(app, event)
            value = app.X_coor.Value;
            
        end

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
            CheckNode=ismember(table(app.NL),table([app.X_coor.Value,app.Y_coor.Value]));
            if(nnz(CheckNode)~=0)
                uialert(app.UIFigure,"Node already exist","Input Error");
            else
                nl = [app.X_coor.Value, app.Y_coor.Value];
                app.NL = [app.NL; nl];
                
                app.NL_Table.Data = app.NL;
                
                app.NoN = size(app.NL,1);
                app.PD = size(app.NL,2) ;
                app.ENL = zeros(app.NoN,app.PD*6);
                app.ENL(:,3:4) = 1;
                app.ENL_Table.Data = app.ENL;
                updateAxes(app);
            end
            
        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
            CheckElement=ismember(table(app.EL),table([app.StartNode.Value,app.EndNode.Value]));
            CheckElement2 = ismember(table(app.EL),table([app.EndNode.Value,app.StartNode.Value]));
             if app.StartNode.Value == app.EndNode.Value
                uialert(app.UIFigure,"Please select different nodes","Input Error") %Single Node Element
             elseif (nnz(CheckElement)~=0)
                   uialert(app.UIFigure,"Element already exist","Input Error");
              
             elseif (nnz(CheckElement2)~=0)
                     uialert(app.UIFigure,"Element already exist","Input Error");
             else
                              
                el = [app.StartNode.Value, app.EndNode.Value];
                
               
                if app.NoN < 2
                    uialert(app.UIFigure,"At least 2 nodes needed to create an element!","Input Error")
                else
                
                app.EL = [app.EL; el];
                
                app.EL_Table.Data = app.EL;
                
                app.NoE = size(app.EL,1);
                
                updateAxes(app);
                end
             end 
        end

        % Value changed function: NodeSizeSlider
        function NodeSizeSliderValueChanged(app, event)
            app.NodeSize = app.NodeSizeSlider.Value;
            updateAxes(app);
        end

        % Value changed function: NodeLabelSwitch
        function NodeLabelSwitchValueChanged(app, event)
            app.NodeSwitch = app.NodeLabelSwitch.Value;
            updateAxes(app);
        end

        % Value changed function: ElementLabelSwitch
        function ElementLabelSwitchValueChanged(app, event)
            app.ElementSwitch = app.ElementLabelSwitch.Value;
            updateAxes(app); 
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.Jet
                    app.colorMap = jet;
                    colormap(app.UIAxes, app.colorMap);
                    
                case app.Cool
                    app.colorMap = cool;
                    colormap(app.UIAxes, app.colorMap); 
                    
                case app.Copper
                    app.colorMap = copper;
                    colormap(app.UIAxes, app.colorMap);
            end
        end

        % Button pushed function: ReadNodeElementListButton
        function ReadNodeElementListButtonPushed(app, event)
            [inputfile,~] = uigetfile({'*.xlsx';'*.slx'}); 'Select File to Open';
            
            if ~isequal(inputfile,0)
                app.NL = [];
                app.EL = [];
                
                nl = readmatrix(inputfile,'Range','A:B');
                el = readmatrix(inputfile, 'Range','C:D');
                
                for i = 1 : size(nl,1)
                    if ~isnan(nl(i,:))
                        
                        app.NL = [app.NL; nl(i,:)];
                    end
                end
                
                for i = 1 : size(el,1)
                    if ~isnan(el(i,:))
                        
                        app.EL = [app.EL; el(i,:)];
                    end
                end
                
                app.NL_Table.Data = app.NL;
                app.EL_Table.Data = app.EL;
                app.NoN = size(app.NL,1);
                app.PD = size(app.NL,2) ;
                app.ENL = zeros(app.NoN,app.PD*6);
                app.ENL(:,1:2) = app.NL ;
                app.ENL(:,3:4) = 1;    
                app.ENL_Table.Data = app.ENL;
                app.NoE = size(app.EL,1);
                updateAxes(app);
            end
            
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            app.EL = [];
            app.NL = [];
            app.NL_Table.Data = app.NL;
            app.EL_Table.Data = app.EL;
            app.NoE = 0 ;
            app.NoN = 0 ;
            app.PD = 0 ;
            app.ENL = [] ;
            app.ENL_Table.Data = app.ENL;
            app.SolveButton.Text = "Solve";
            updateAxes(app)
        end

        % Button pushed function: ApplyBCButton
        function ApplyBCButtonPushed(app, event)
            %ENL is already generated at this point
            if app.NodeNumberEditField.Value < 1
                uialert(app.UIFigure,"Please select a Node to apply the conditions","Input Error")
            else
                app.ENL(:,1:2) = app.NL ;
                i = app.NodeNumberEditField.Value ;
                magnitude = app.MagnitudeEditField.Value ;
                xory = 0;
                if app.YButton.Value
                    xory = 1;
                end
                if app.DisplacementButton.Value
                    col = 9;
                    app.ENL(i,3+xory) = -1;
                elseif app.ForceButton.Value
                    col = 11 ;
                    app.ENL(i,3+xory) = 1;

                end
    
                app.ENL(i,col+xory) = magnitude;
                app.ENL_Table.Data = app.ENL;

            end


        end

        % Button pushed function: ClearBCsButton
        function ClearBCsButtonPushed(app, event)
            app.ENL = zeros(app.NoN,app.PD*6);
            app.ENL_Table.Data = app.ENL;
            app.SolveButton.Text = "Solve";
        end

        % Value changed function: Field_E
        function Field_EValueChanged(app, event)
            value = app.Field_E.Value;
            app.E = value ;
        end

        % Value changed function: Field_A
        function Field_AValueChanged(app, event)
            value = app.Field_A.Value;
            app.A = value ;
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            FEM11(app);
            dat = zeros(app.NoN,4);
            dat(:,1:2) = app.ENL(:,1:2);
            dat(:,3:4) = app.ENL(:,9:10);
            app.ENL_Table.Data = app.ENL;
            app.DisplacementsTable.Data = dat;
            app.SolveButton.Text = "Solved";
        end

        % Value changed function: MagnificationSlider
        function MagnificationSliderValueChanged(app, event)
            app.Mag = app.MagnificationSlider.Value;
            post_process(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged2(app, event)

            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.Jet
                    app.colorMap = jet;
                    colormap(app.UIAxes, app.colorMap);
                    colormap(app.UIAxes2, app.colorMap);
                    
                case app.Cool
                    app.colorMap = cool;
                    colormap(app.UIAxes, app.colorMap);
                    colormap(app.UIAxes2, app.colorMap);
                    
                case app.Copper
                    app.colorMap = copper;
                    colormap(app.UIAxes, app.colorMap);
                    colormap(app.UIAxes2, app.colorMap);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreProcessProcessTab = uitab(app.TabGroup);
            app.PreProcessProcessTab.Title = 'Pre Process & Process';
            app.GridLayout = uigridlayout(app.PreProcessProcessTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GenerateNodeButton = uibutton(app.GridLayout, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = 'Generate Node';
            app.NL_Table = uitable(app.GridLayout);
            app.NL_Table.ColumnName = {'X'; 'Y'};
            app.NL_Table.RowName = {};
            app.GenerateElementButton = uibutton(app.GridLayout, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = 'Generate Element';
            app.EL_Table = uitable(app.GridLayout);
            app.EL_Table.ColumnName = {'Start Node'; 'End Node'};
            app.EL_Table.RowName = {};
            app.ElementListLabel = uilabel(app.GridLayout);
            app.ElementListLabel.Text = 'Element List';
            app.ReadNodeElementListButton = uibutton(app.GridLayout, 'push');
            app.ReadNodeElementListButton.ButtonPushedFcn = createCallbackFcn(app, @ReadNodeElementListButtonPushed, true);
            app.ReadNodeElementListButton.Text = 'Read Node/Element List';
            app.XCoordinateEditFieldLabel = uilabel(app.GridLayout);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.X_coor = uieditfield(app.GridLayout, 'numeric');
            app.X_coor.ValueChangedFcn = createCallbackFcn(app, @X_coorValueChanged, true);
            app.YCoordinateEditFieldLabel = uilabel(app.GridLayout);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.Y_coor = uieditfield(app.GridLayout, 'numeric');
            app.StartNodeEditFieldLabel = uilabel(app.GridLayout);
            app.StartNodeEditFieldLabel.Text = 'Start Node';
            app.StartNode = uieditfield(app.GridLayout, 'numeric');
            app.EndNodeEditFieldLabel = uilabel(app.GridLayout);
            app.EndNodeEditFieldLabel.Text = 'End Node';
            app.EndNode = uieditfield(app.GridLayout, 'numeric');
            app.NodeSizeSliderLabel = uilabel(app.GridLayout);
            app.NodeSizeSliderLabel.Text = 'Node Size';
            app.NodeSizeSlider = uislider(app.GridLayout);
            app.NodeSizeSlider.Limits = [1 100];
            app.NodeSizeSlider.ValueChangedFcn = createCallbackFcn(app, @NodeSizeSliderValueChanged, true);
            app.NodeSizeSlider.Value = 10;
            app.NodeCoordinatesLabel = uilabel(app.GridLayout);
            app.NodeCoordinatesLabel.Text = {'Node'; 'Coordinates'};
            app.NodeListLabel = uilabel(app.GridLayout);
            app.NodeListLabel.Text = 'Node List';
            app.ElementsLabel = uilabel(app.GridLayout);
            app.ElementsLabel.Text = 'Elements';
            app.NodeLabelSwitchLabel = uilabel(app.GridLayout);
            app.NodeLabelSwitchLabel.Text = 'Node Label';
            app.NodeLabelSwitch = uiswitch(app.GridLayout, 'slider');
            app.NodeLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelSwitchValueChanged, true);
            app.NodeLabelSwitch.Value = 'On';
            app.ElementLabelSwitchLabel = uilabel(app.GridLayout);
            app.ElementLabelSwitchLabel.Text = 'Element Label';
            app.ElementLabelSwitch = uiswitch(app.GridLayout, 'slider');
            app.ElementLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementLabelSwitchValueChanged, true);
            app.ElementLabelSwitch.Value = 'On';
            app.DirectionButtonGroup = uibuttongroup(app.GridLayout);
            app.DirectionButtonGroup.Title = 'Direction';
            app.XButton = uiradiobutton(app.DirectionButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.DirectionButtonGroup);
            app.YButton.Text = 'Y';
            app.TypeButtonGroup = uibuttongroup(app.GridLayout);
            app.TypeButtonGroup.Title = 'Type';
            app.ForceButton = uiradiobutton(app.TypeButtonGroup);
            app.ForceButton.Text = 'Force';
            app.ForceButton.Value = true;
            app.DisplacementButton = uiradiobutton(app.TypeButtonGroup);
            app.DisplacementButton.Text = 'Displacement';
            app.ApplyBCButton = uibutton(app.GridLayout, 'push');
            app.ApplyBCButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyBCButtonPushed, true);
            app.ApplyBCButton.Text = 'Apply BC';
            app.ClearallButton = uibutton(app.GridLayout, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.MagnitudePanel = uipanel(app.GridLayout);
            app.MagnitudePanel.Title = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.MagnitudePanel, 'numeric');
            app.NodePanel = uipanel(app.GridLayout);
            app.NodePanel.Title = 'Node';
            app.NodeNumberEditField = uieditfield(app.NodePanel, 'numeric');
            app.ClearBCsButton = uibutton(app.GridLayout, 'push');
            app.ClearBCsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearBCsButtonPushed, true);
            app.ClearBCsButton.Text = 'Clear BCs';
            app.EPanel = uipanel(app.GridLayout);
            app.EPanel.Title = 'E';
            app.Field_E = uieditfield(app.EPanel, 'numeric');
            app.Field_E.ValueChangedFcn = createCallbackFcn(app, @Field_EValueChanged, true);
            app.APanel = uipanel(app.GridLayout);
            app.APanel.Title = 'A';
            app.Field_A = uieditfield(app.APanel, 'numeric');
            app.Field_A.ValueChangedFcn = createCallbackFcn(app, @Field_AValueChanged, true);
            app.SolveButton = uibutton(app.GridLayout, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ENL_Table = uitable(app.GridLayout);
            app.ENL_Table.ColumnName = {'X'; 'Y'; 'X'; 'Y'; 'X'; 'Y'; 'X'; 'Y'; 'X'; 'Y'; 'X'; 'Y'};
            app.ENL_Table.RowName = {};
            app.ExtendedNodeListLabel = uilabel(app.GridLayout);
            app.ExtendedNodeListLabel.Text = 'Extended Node List';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout2 = uigridlayout(app.ResultsTab);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.MagnificationSliderLabel = uilabel(app.GridLayout2);
            app.MagnificationSliderLabel.Text = 'Magnification';
            app.MagnificationSlider = uislider(app.GridLayout2);
            app.MagnificationSlider.Limits = [1 400];
            app.MagnificationSlider.ValueChangedFcn = createCallbackFcn(app, @MagnificationSliderValueChanged, true);
            app.MagnificationSlider.Value = 1;
            app.DisplacementsTable = uitable(app.GridLayout2);
            app.DisplacementsTable.ColumnName = {'X'; 'Y'; 'X displacement'; 'Y displacement'};
            app.DisplacementsTable.RowName = {};
            app.ButtonGroup = uibuttongroup(app.GridLayout2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged2, true);
            app.ButtonGroup.Title = 'Colormap';
            app.Jet = uitogglebutton(app.ButtonGroup);
            app.Jet.Icon = fullfile(pathToMLAPP, 'jet.jpeg');
            app.Jet.Text = '';
            app.Jet.Value = true;
            app.Cool = uitogglebutton(app.ButtonGroup);
            app.Cool.Icon = fullfile(pathToMLAPP, 'cool.jpeg');
            app.Cool.Text = '';
            app.Copper = uitogglebutton(app.ButtonGroup);
            app.Copper.Icon = fullfile(pathToMLAPP, 'copper.jpeg');
            app.Copper.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end